classdef Trab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageAxes                   matlab.ui.control.UIAxes
        PredefinedImagesLabel       matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        LoadButton                  matlab.ui.control.Button
        PrewittButton               matlab.ui.control.Button
        SaltPepperButton            matlab.ui.control.Button
        PassaBaixaMdiaButton        matlab.ui.control.Button
        PassaBaixaMedianaButton     matlab.ui.control.Button
        GreyScaleButton             matlab.ui.control.Button
        SobelButton                 matlab.ui.control.Button
        RobertsButton               matlab.ui.control.Button
        LogButton                   matlab.ui.control.Button
        CannyButton                 matlab.ui.control.Button
        ZeroCrossButton             matlab.ui.control.Button
        PassaAltaBasicoButton       matlab.ui.control.Button
        PassaAltoReforoButton       matlab.ui.control.Button
        WaterShedButton             matlab.ui.control.Button
        ThresholdButton             matlab.ui.control.Button
        HistogramaButton            matlab.ui.control.Button
        HistogramaAdaptativoButton  matlab.ui.control.Button
        ContagemdeObjetosButton     matlab.ui.control.Button
        ManterImgButton             matlab.ui.control.Button
        ValoresEditFieldLabel       matlab.ui.control.Label
        ValoresEditField            matlab.ui.control.EditField
        MtodoHistEditFieldLabel     matlab.ui.control.Label
        MtodoHistEditField          matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Image % Description
        NewIm % Description
        ValorNovo = 0 % Description
        MetHist = 0
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            app.Image = im;
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
           
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
          
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
        end  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            im = app.Image;
            if app.ValorNovo == 0 | app.ValorNovo == '0'
                J = imnoise(im, 'salt & pepper', 0.01);
            else 
                J = imnoise(im, 'salt & pepper', str2double(app.ValorNovo));
            end
            app.NewIm = J;
            imshow(J);
            return;
        end

        % Button pushed function: PassaBaixaMdiaButton
        function PassaBaixaMdiaButtonPushed(app, event)
            im = app.Image;
            
            %h = [1/9 1/9 1/9; 1/9 1/9 1/9; 1/9 1/9 1/9];  
            %h = fspecial('average', 3);
            
            if app.ValorNovo == 0 | app.ValorNovo == '0'
                h = fspecial('average', 3);
            else
                h = fspecial('average', str2double(app.ValorNovo));
            end
            
            pb = imfilter(im,h);
            app.NewIm = pb;
            imshow(pb);     
        end

        % Button pushed function: PassaBaixaMedianaButton
        function PassaBaixaMedianaButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            J = medfilt2(J);
            app.NewIm = J;
            imshow(J); 
        end

        % Button pushed function: GreyScaleButton
        function GreyScaleButtonPushed(app, event)
            im = app.Image;
            J = rgb2gray(im);
            app.NewIm = J;
            imshow(J);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'prewitt');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'Sobel');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: RobertsButton
        function RobertsButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'Roberts');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'Roberts');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'Canny');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: ZeroCrossButton
        function ZeroCrossButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end
            K = edge(J,'zerocross');
            app.NewIm = K;
            imshow(K);
        end

        % Button pushed function: PassaAltaBasicoButton
        function PassaAltaBasicoButtonPushed(app, event)
            im = app.Image;
            h=[-1/9 -1/9 -1/9; -1/9 8/9 -1/9; -1/9 -1/9 -1/9;];
            pa = imfilter(im,h);
            app.NewIm = pa;
            imshow(pa);
        end

        % Button pushed function: PassaAltoReforoButton
        function PassaAltoReforoButtonPushed(app, event)
            im = app.Image;
            A = 1.7;
            h=[-1/9 -1/9 -1/9; -1/9 8/9 -1/9; -1/9 -1/9 -1/9;];
            pa = imfilter(im,h);
            
            if app.ValorNovo == 0 | app.ValorNovo == '0'
                ar=(A-1)*im+pa;
            else
                ar=(str2double(app.ValorNovo)-1)*im+pa; 
            end
            
            app.NewIm = ar;
            imshow(ar);
        end

        % Button pushed function: WaterShedButton
        function WaterShedButtonPushed(app, event)
            im = app.Image;
            J = watershed(im);
            app.NewIm = J;
            imshow(J);
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            im = app.Image;
            if app.ValorNovo == 0 | app.ValorNovo == '0'
                J= im2bw(im,0.2);
            else
                J= im2bw(im,str2double(app.ValorNovo));
            end
            app.NewIm = J;
            imshow(J);
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)
            im = app.Image;
            J = im;
            if size(J,3)==3
                J = rgb2gray(im);
            end  
            imhist(J);
            app.NewIm = J;
            figure(1);
        end

        % Button pushed function: HistogramaAdaptativoButton
        function HistogramaAdaptativoButtonPushed(app, event)
            im = app.Image;
            K = im;
            if size(K,3)==3
                K = rgb2gray(im);
            end 
            mtd = 'rayleigh';
            qtt = 0.02;
            
            if app.ValorNovo ~= 0 | app.ValorNovo == '0'
                qtt = str2double(app.ValorNovo);
            end
            
            if app.MetHist ~= 0 | app.MetHist == '0'
                mtd = app.MetHist;
            end
            
            %J = adapthisteq(K,'clipLimit',0.02,'Distribution','rayleigh');
            J = adapthisteq(K,'clipLimit',qtt,'Distribution',mtd);
            app.NewIm = J;
            imshow(J);
        end

        % Button pushed function: ContagemdeObjetosButton
        function ContagemdeObjetosButtonPushed(app, event)
            im = app.Image;
            
            %Passa baixa média
            
            k = im;
            if size(im,3)==3
                k = rgb2gray(im);
            end
            
            %binariza
            if app.ValorNovo == 0 | app.ValorNovo == '0'
                J= imbinarize(k);
            else
                J= imbinarize(k, str2double(app.ValorNovo));
            end
            
            %faz a "limpesa da imagem"
            bi = imclearborder(J);
            ib = ~bi;
            bi = imclearborder(ib);
            %imshow(bi);
            
            %faz a contagem de objetos
            img = imfill(bi, 'holes');
            [B,L] = bwboundaries(img, 'noholes'); 
            
            stats = regionprops(L, 'Area');
            qdd = sum([stats.Area]>35);
            
            imshow(img);
            title(sprintf('\\fontsize{16}{Existem %d objetos na imagem}', qdd));
        end

        % Button pushed function: ManterImgButton
        function ManterImgButtonPushed(app, event)
            app.Image = app.NewIm;
            %app.updateimage(app.NewIm);
        end

        % Value changing function: ValoresEditField
        function ValoresEditFieldValueChanging(app, event)
            
        end

        % Value changed function: ValoresEditField
        function ValoresEditFieldValueChanged(app, event)
            value = app.ValoresEditField.Value;
            app.ValorNovo = value;
            
        end

        % Value changed function: MtodoHistEditField
        function MtodoHistEditFieldValueChanged(app, event)
            value = app.MtodoHistEditField.Value;
            app.MetHist = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Peppers', 'Corn'};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SaltPepperButton = uibutton(app.UIFigure, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = {'Salt & Pepper'; ''};
            app.PassaBaixaMdiaButton = uibutton(app.UIFigure, 'push');
            app.PassaBaixaMdiaButton.ButtonPushedFcn = createCallbackFcn(app, @PassaBaixaMdiaButtonPushed, true);
            app.PassaBaixaMdiaButton.Text = {'PassaBaixa - Média'; ''};
            app.PassaBaixaMedianaButton = uibutton(app.UIFigure, 'push');
            app.PassaBaixaMedianaButton.ButtonPushedFcn = createCallbackFcn(app, @PassaBaixaMedianaButtonPushed, true);
            app.PassaBaixaMedianaButton.Text = 'PassaBaixa - Mediana';
            app.GreyScaleButton = uibutton(app.UIFigure, 'push');
            app.GreyScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyScaleButtonPushed, true);
            app.GreyScaleButton.Text = 'GreyScale';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RobertsButton = uibutton(app.UIFigure, 'push');
            app.RobertsButton.ButtonPushedFcn = createCallbackFcn(app, @RobertsButtonPushed, true);
            app.RobertsButton.Text = 'Roberts';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            app.CannyButton = uibutton(app.UIFigure, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.ZeroCrossButton = uibutton(app.UIFigure, 'push');
            app.ZeroCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroCrossButtonPushed, true);
            app.ZeroCrossButton.Text = 'ZeroCross';
            app.PassaAltaBasicoButton = uibutton(app.UIFigure, 'push');
            app.PassaAltaBasicoButton.ButtonPushedFcn = createCallbackFcn(app, @PassaAltaBasicoButtonPushed, true);
            app.PassaAltaBasicoButton.Text = 'PassaAlta - Basico';
            app.PassaAltoReforoButton = uibutton(app.UIFigure, 'push');
            app.PassaAltoReforoButton.ButtonPushedFcn = createCallbackFcn(app, @PassaAltoReforoButtonPushed, true);
            app.PassaAltoReforoButton.Text = 'PassaAlto - Reforço';
            app.WaterShedButton = uibutton(app.UIFigure, 'push');
            app.WaterShedButton.ButtonPushedFcn = createCallbackFcn(app, @WaterShedButtonPushed, true);
            app.WaterShedButton.Text = 'WaterShed';
            app.ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Text = 'Threshold';
            app.HistogramaButton = uibutton(app.UIFigure, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma';
            app.HistogramaAdaptativoButton = uibutton(app.UIFigure, 'push');
            app.HistogramaAdaptativoButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaAdaptativoButtonPushed, true);
            app.HistogramaAdaptativoButton.Text = 'Histograma Adaptativo';
            app.ContagemdeObjetosButton = uibutton(app.UIFigure, 'push');
            app.ContagemdeObjetosButton.ButtonPushedFcn = createCallbackFcn(app, @ContagemdeObjetosButtonPushed, true);
            app.ContagemdeObjetosButton.Text = 'Contagem de Objetos';
            app.ManterImgButton = uibutton(app.UIFigure, 'push');
            app.ManterImgButton.ButtonPushedFcn = createCallbackFcn(app, @ManterImgButtonPushed, true);
            app.ManterImgButton.Text = 'Manter Img';
            app.ValoresEditFieldLabel = uilabel(app.UIFigure);
            app.ValoresEditFieldLabel.Text = 'Valores';
            app.ValoresEditField = uieditfield(app.UIFigure, 'text');
            app.ValoresEditField.ValueChangedFcn = createCallbackFcn(app, @ValoresEditFieldValueChanged, true);
            app.ValoresEditField.ValueChangingFcn = createCallbackFcn(app, @ValoresEditFieldValueChanging, true);
            app.MtodoHistEditFieldLabel = uilabel(app.UIFigure);
            app.MtodoHistEditFieldLabel.Text = 'Método Hist.';
            app.MtodoHistEditField = uieditfield(app.UIFigure, 'text');
            app.MtodoHistEditField.ValueChangedFcn = createCallbackFcn(app, @MtodoHistEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end